classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        MaskLabel            matlab.ui.control.Label
        MaskImage            matlab.ui.control.Image
        OutputLabel          matlab.ui.control.Label
        EdgeLabel            matlab.ui.control.Label
        InputLabel           matlab.ui.control.Label
        OutputImage          matlab.ui.control.Image
        EdgeImage            matlab.ui.control.Image
        PathFileField        matlab.ui.control.EditField
        BrowseImageButton    matlab.ui.control.Button
        MethodDropDown       matlab.ui.control.DropDown
        MethodDropDownLabel  matlab.ui.control.Label
        InputImage           matlab.ui.control.Image
        ExecuteButton        matlab.ui.control.Button
    end

    methods (Access = private)
        function rgb = toRGB(app, img)
            % Convert any grayscale/logical to RGB uint8
            if islogical(img)
                img = uint8(img .* 255);
            elseif ~isa(img, 'uint8')
                img = im2uint8(img);
            end
            
            if ndims(img) == 2
                rgb = cat(3, img, img, img); % replicate grayscale to RGB
            else
                rgb = img;
            end
        end

    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseImageButton
        function BrowseImageButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.png;*.jpeg;*.bmp', 'Image Files'});
            
            if isequal(file, 0)
                return;
            end
            
            fullpath = fullfile(path, file);
            
            app.PathFileField.Value = fullpath;
            
            img = imread(fullpath);
            app.InputImage.ImageSource = img;
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            % Get image from InputImage
            src = app.InputImage.ImageSource;
        
            if isempty(src)
                uialert(app.UIFigure, 'No image loaded. Please browse an image first.', 'Error');
                return;
            end
        
            % If ImageSource is a filename, read it; if it's already an array, use directly
            if ischar(src) || isstring(src)
                I = imread(src);
            else
                I = src;
            end
        
            % Ensure uint8 for consistency
            if ~isa(I, 'uint8')
                I = im2uint8(I);
            end
        
            % Convert to grayscale for edge detection
            if ndims(I) == 3
                Igray = rgb2gray(I);
            else
                Igray = I;
            end
        
            IgrayD = im2double(Igray);
            method = app.MethodDropDown.Value;
        
            switch method
                case 'Laplace'
                    Lap = [0  1  0;
                           1 -4  1;
                           0  1  0];
        
                    resp = imfilter(IgrayD, Lap, 'replicate', 'conv');
                    mag  = abs(resp);
                    T = graythresh(mag);
                    E = mag > T;
        
                case 'LoG'
                    sigma = 1.0;
                    I_smooth = imgaussfilt(IgrayD, sigma);
        
                    Lap = [0  1  0;
                           1 -4  1;
                           0  1  0];
        
                    resp = imfilter(I_smooth, Lap, 'replicate', 'conv');
                    mag  = abs(resp);
                    T = graythresh(mag);
                    E = mag > T;
        
                case 'Sobel'
                    Gx = [ -1  0  1;
                           -2  0  2;
                           -1  0  1 ];
                    Gy = [ -1 -2 -1;
                            0  0  0;
                            1  2  1 ];
        
                    Fx = imfilter(IgrayD, Gx, 'replicate', 'conv');
                    Fy = imfilter(IgrayD, Gy, 'replicate', 'conv');

                    mag = sqrt(Fx.^2 + Fy.^2);
                    mag = mag / max(mag(:) + eps);  % normalize
                    T = graythresh(mag);
                    E = mag >= T;
        
                case 'Prewitt'
                    Gx = [ -1  0  1;
                           -1  0  1;
                           -1  0  1 ];
                    Gy = [ -1 -1 -1;
                            0  0  0;
                            1  1  1 ];
        
                    Fx = imfilter(IgrayD, Gx, 'replicate', 'conv');
                    Fy = imfilter(IgrayD, Gy, 'replicate', 'conv');
                    mag = sqrt(Fx.^2 + Fy.^2);
                    mag = mag / max(mag(:) + eps);
                    T = graythresh(mag);
                    E = mag >= T;
        
                case 'Roberts'
                    Gx = [1  0;
                          0 -1];
                    Gy = [0  1;
                         -1  0];
        
                    Fx = imfilter(IgrayD, Gx, 'replicate', 'conv');
                    Fy = imfilter(IgrayD, Gy, 'replicate', 'conv');
                    mag = sqrt(Fx.^2 + Fy.^2);
                    mag = mag / max(mag(:) + eps);
                    T = graythresh(mag);
                    E = mag >= T;
        
                case 'Canny'
                    E = edge(Igray, 'canny');
        
                otherwise
                    E = edge(Igray, 'canny');
            end
       
            E = logical(E);
    
            % Show edge image
            app.EdgeImage.ImageSource = app.toRGB(E);     

            % Object segmentatio
            mask = E;

            % Connect broken edges a bit
            mask = bwmorph(mask, 'bridge');
            mask = bwmorph(mask, 'close');   % extra connect for small gaps

            % Morphological closing to smooth / connect contours
            seClose = strel('disk', 6);
            mask = imclose(mask, seClose);

            % Fill interior of closed contours
            mask = imfill(mask, 'holes');

            % Remove noise 
            minArea = round(0.001 * numel(mask));  % tweak 0.001 if needed
            mask = bwareaopen(mask, minArea);

            % Remove objects touching image border (usually background)
            mask = imclearborder(mask);

            app.MaskImage.ImageSource = app.toRGB(mask);

            % Apply mask to original image: background = white
            if ndims(I) == 3
                mask3 = repmat(mask, [1 1 3]);
                out = I;
                out(~mask3) = 255;   % set background white
            else
                out = I;
                out(~mask) = 255;
            end
        
            % Show output image
            app.OutputImage.ImageSource = app.toRGB(out);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ExecuteButton = uibutton(app.UIFigure, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.InputImage = uiimage(app.UIFigure);
            app.MethodDropDownLabel = uilabel(app.UIFigure);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.UIFigure);
            app.MethodDropDown.Items = {'Laplace', 'LoG', 'Sobel', 'Prewitt', 'Roberts', 'Canny'};
            app.MethodDropDown.Value = 'Laplace';
            app.BrowseImageButton = uibutton(app.UIFigure, 'push');
            app.BrowseImageButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseImageButtonPushed, true);
            app.BrowseImageButton.Text = 'Browse Image';
            app.PathFileField = uieditfield(app.UIFigure, 'text');
            app.PathFileField.Value = '(No file selected)';
            app.EdgeImage = uiimage(app.UIFigure);
            app.OutputImage = uiimage(app.UIFigure);
            app.InputLabel = uilabel(app.UIFigure);
            app.InputLabel.Text = 'Input';
            app.EdgeLabel = uilabel(app.UIFigure);
            app.EdgeLabel.Text = 'Edge';
            app.OutputLabel = uilabel(app.UIFigure);
            app.OutputLabel.Text = 'Output';
            app.MaskImage = uiimage(app.UIFigure);
            app.MaskLabel = uilabel(app.UIFigure);
            app.MaskLabel.Text = 'Mask';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end